classdef Pricify_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Pricify20UIFigure          matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GridWaiting                matlab.ui.container.GridLayout
        PricifyImage               matlab.ui.control.Image
        TabGroup                   matlab.ui.container.TabGroup
        WelcomeTab                 matlab.ui.container.Tab
        GridTab1                   matlab.ui.container.GridLayout
        InfoLabel                  matlab.ui.control.Label
        WelcomeLabel               matlab.ui.control.Label
        VIRALabel                  matlab.ui.control.Label
        SearchableDatabaseTab      matlab.ui.container.Tab
        GridTab2                   matlab.ui.container.GridLayout
        Excel_Export               matlab.ui.control.Button
        PartNumberTextArea         matlab.ui.control.TextArea
        Searched_Table             matlab.ui.control.Table
        SourceDD                   matlab.ui.control.DropDown
        PartNumbersLabel           matlab.ui.control.Label
        ExactButton                matlab.ui.control.StateButton
        QuotedPNButton             matlab.ui.control.StateButton
        LeadTimeButton             matlab.ui.control.StateButton
        SearchButton               matlab.ui.control.Button
        OrderDD                    matlab.ui.control.DropDown
        RefreshButton              matlab.ui.control.Button
        PricingAssistantTab        matlab.ui.container.Tab
        GridTab3                   matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        ToleranceSlider            matlab.ui.control.Slider
        LastOrderCheckBox          matlab.ui.control.CheckBox
        MinMaxBreakdownCheckBox    matlab.ui.control.CheckBox
        NotFoundTable              matlab.ui.control.Table
        RunButton                  matlab.ui.control.Button
        OutputTable                matlab.ui.control.Table
        ExtractButton              matlab.ui.control.Button
        PricifySuggestionCheckBox  matlab.ui.control.CheckBox
        ImportInquirylistButton    matlab.ui.control.Button
        PreviewTable               matlab.ui.control.Table
    end

    
    properties (Access = private)
        Property % Description
        exactSearch;
        ExtractedResults cell;
        DealtDB table;          
        UndealtDB table;
        Temp;
    end
        
    methods (Access = public)
        
        function cleanedPN = cleanPartNumber(obj, partNumber)

            partNumber = char(partNumber); % Convert to char array if necessary
        
            % Remove non-word characters (i.e., anything except letters, digits, and underscores)
            cleanedPN = regexprep(partNumber, '\W+', '');
            
            % Remove underscores and convert to lowercase
            cleanedPN = strrep(cleanedPN, '_', '');
            cleanedPN = lower(cleanedPN);
        end

        
        function CenterPanel(app)
            %Get the size of the app window (figure)
            appWindowSize = app.Pricify20UIFigure.Position(3:4); % Width and Height of the app window
            
            fixedPanelWidth = appWindowSize(1); 
            fixedPanelHeight = appWindowSize(2); 
            
            %Calculate the position to center the panel
            newX = (appWindowSize(1) - fixedPanelWidth) / 2;
            newY = (appWindowSize(2) - fixedPanelHeight) / 2;
            
            %Update the panel's position
            app.Panel.Position = [newX, newY, fixedPanelWidth, fixedPanelHeight];
        end


        function [tableData, extractedRow] = processResults(app, results, query, tableData)
            totalUP2C = 0; totalQuantity = 0; totalUPRatio = 0; count = 0;
            sourceCounts = containers.Map;
            
            for j = 1:height(results)
                row = results(j, :);
                % Extract and format data (handle missing values)
                [order, dateStr, partNumber, up2cStr, purchasedUPStr, upRatioStr, qtyStr, sourceStr, leadTimeStr] = formatRow(app, row);
                
                % Add to table
                tableData = [tableData; {order, dateStr, partNumber, up2cStr, purchasedUPStr, upRatioStr, qtyStr, sourceStr, leadTimeStr}];
                
                % Update averages
                if ~isnan(row.UP2C)
                    totalUP2C = totalUP2C + row.UP2C;
                end
                if ~isnan(row.FinalQuantity)
                    totalQuantity = totalQuantity + row.FinalQuantity;
                end
                if ~isnan(row.UPRatio)
                    totalUPRatio = totalUPRatio + row.UPRatio;
                end
                count = count + 1;
                
                % Track sources
                if ~ismissing(row.Source)
                    source = char(row.Source);
                    if isKey(sourceCounts, source)
                        sourceCounts(source) = sourceCounts(source) + 1;
                    else
                        sourceCounts(source) = 1;
                    end
                end
            end
            
            % Calculate averages and favorite source
            avgUP2C = totalUP2C / count;
            avgQty = totalQuantity / count;
            avgUPRatio = totalUPRatio / count;
            if ~isempty(sourceCounts)
                favSource = sourceCounts.keys{argmax(cell2mat(sourceCounts.values))};
            else
                favSource = '-';
            end
            
            % Add summary row
            summaryRow = {'', '', query, sprintf('UP2C: %.2f', avgUP2C), '', sprintf('UP Ratio: %.2f', avgUPRatio), sprintf('Qty: %d', avgQty), sprintf('Source: %s', favSource), ''};
            tableData = [tableData; summaryRow];
            
            % Prepare extracted data
            lastRow = results(end, :);
            [order, dateStr, partNumber, up2cStr, purchasedUPStr, upRatioStr, qtyStr, sourceStr, leadTimeStr] = formatRow(app, lastRow);
            extractedRow = {query, avgUP2C, avgQty, avgUPRatio, favSource, order, dateStr, partNumber, up2cStr, purchasedUPStr, upRatioStr, qtyStr, sourceStr, leadTimeStr};
%             extractedRow = {query, avgUP2C, avgQty, avgUPRatio, favSource};
        end
        
        function [order, dateStr, partNumber, up2cStr, purchasedUPStr, upRatioStr, qtyStr, sourceStr, leadTimeStr] = formatRow(app, row)
            order = num2str(row.Order);
            dateStr = datestr(row.Date, 'dd-mmm-yyyy');
            partNumber = char(row.PartNumber);
            
            up2cStr = '-'; purchasedUPStr = '-'; upRatioStr = '-'; qtyStr = '-';
            if ~isnan(row.UP2C), up2cStr = sprintf('%.2f', row.UP2C); end
            if ~isnan(row.PurchasedUP), purchasedUPStr = sprintf('%.3f', row.PurchasedUP); end
            if ~isnan(row.UPRatio), upRatioStr = sprintf('%.2f', row.UPRatio); end
            if ~isnan(row.FinalQuantity), qtyStr = num2str(row.FinalQuantity); end
            
            sourceStr = '-'; leadTimeStr = '-';
            if ~ismissing(row.Source), sourceStr = char(row.Source); end
            if ~isnan(row.LeadTime), leadTimeStr = num2str(row.LeadTime); end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button down function: WelcomeTab
        function WelcomeTabButtonDown(app, event)
            
        end

        % Button down function: PricingAssistantTab
        function PricingAssistantTabButtonDown(app, event)


            app.DealtDB = readtable('db\Merged Dataset.xlsx', 'Sheet', 'Dealt_Data');
            app.UndealtDB = readtable('db\Merged Dataset.xlsx', 'Sheet', 'Undealt_Data');   
            app.MinMaxBreakdownCheckBox.Value = false;
            app.PricifySuggestionCheckBox.Value = false;
            app.LastOrderCheckBox.Value = false;
            app.PreviewTable.Data = '';
            app.NotFoundTable.Data = '';
            app.OutputTable.Data = '';
            numCols = numel(app.OutputTable.ColumnName);
            app.OutputTable.ColumnName = repmat({''}, 1, numCols);
            app.ToleranceSlider.Value = 0.5;
            removeStyle(app.PreviewTable);


        end

        % Button down function: SearchableDatabaseTab
        function SearchableDatabaseTabButtonDown(app, event)

            app.DealtDB = readtable('db\Merged Dataset.xlsx', 'Sheet', 'Dealt_Data');
            app.UndealtDB = readtable('db\Merged Dataset.xlsx', 'Sheet', 'Undealt_Data');   
            app.ExactButton.Value = false;
            app.LeadTimeButton.Value = false;
            app.QuotedPNButton.Value = false;
            app.OrderDD.Items=["Order";unique(app.DealtDB.Order)];
            app.SourceDD.Items=["Source";unique(app.DealtDB.Source)];
            app.PartNumberTextArea.Value = '';
            app.Searched_Table.Data = '';
            app.OrderDD.Value="Order";
            app.SourceDD.Value="Source";
            removeStyle(app.Searched_Table);


        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)

            progressDlg = uiprogressdlg(app.Pricify20UIFigure, ...
                'Title', 'Searching...', ...
                'Message', 'Please wait :)', ...
                'Indeterminate', true, ...
                'Cancelable', false);

                removeStyle(app.Searched_Table);
            % Get queries from text area
                queries = app.PartNumberTextArea.Value; % Cell array of strings
                queries = strtrim(queries); % Trim whitespace
                queries = queries(~cellfun(@isempty, queries)); % Remove empty lines
                
                % Validate input
                if isempty(queries)
                    if ~app.QuotedPNButton.Value && ~app.LeadTimeButton.Value && ...
                       strcmp(app.OrderDD.Value, 'Order') && strcmp(app.SourceDD.Value, 'Source')
                        uialert(app.Pricify20UIFigure, 'Please enter at least one Part Number to search.', 'Input Error');
                        close(progressDlg);
                        return;
                    else
                        % If no part number is entered but filters are active, use an empty query.
                        queries = {''};
                    end
                end

                % Check for invalid part numbers (less than 3 characters)
                invalid = cellfun(@(x) (length(x) >= 1 && length(x) < 3), queries);
                if any(invalid)
                    uialert(app.Pricify20UIFigure, ...
                        sprintf('Invalid Part Numbers (less than 3 characters):\n%s', strjoin(queries(invalid), ', ')), ...
                        'Input Error');
                    return;
                end
                
                
                % Clear previous results
                app.Searched_Table.Data = [];
                app.ExtractedResults = {};
                
                % Clean part numbers in database
                cleanedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('PartNumber'), 'UniformOutput', false);
                cleanedQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('QuotedPN'), 'UniformOutput', false);
                
                % Prepare results table
                results = cell(0,10);
                notFoundQueries = {}; % Initialize list to collect not found queries
                
                for q = 1:length(queries)
                    query = queries{q};
                    cleanQuery = app.cleanPartNumber(query);
                    
%                     % Perform search
%                     if app.ExactButton.Value == true
%                         matches = strcmp(cleanedPN, cleanQuery) | strcmp(cleanedQuotedPN, cleanQuery);
%                     else
%                         matches = contains(cleanedPN, cleanQuery, 'IgnoreCase', true) | ...
%                                  contains(cleanedQuotedPN, cleanQuery, 'IgnoreCase', true);
%                     end
                            
                    % If the query is empty, match all rows.
                    if isempty(cleanQuery)
                        matches = true(size(cleanedPN));
                    elseif app.ExactButton.Value == true
                        matches = strcmp(cleanedPN, cleanQuery) | strcmp(cleanedQuotedPN, cleanQuery);
                    else
                        matches = contains(cleanedPN, cleanQuery, 'IgnoreCase', true) | ...
                                  contains(cleanedQuotedPN, cleanQuery, 'IgnoreCase', true);
                    end

                    matchedData = app.DealtDB(matches,:);
                    
                    % Apply Order filter
                    if ~strcmp(app.OrderDD.Value, 'Order')
%                         matchedData = matchedData(matchedData.Order == app.OrderDD.Value, :);
                        orderArray = string(matchedData.Order);
                        matchedData = matchedData(orderArray == app.OrderDD.Value, :);
                    end
                    
                    % Apply Source filter
                    selectedSource = app.SourceDD.Value;
                    if ~strcmp(selectedSource, 'Source')
                        matchedData = matchedData(strcmp(matchedData.Source, selectedSource), :);
                    end
                   
                    % Apply Lead Time filter if LeadTimeButton is pressed
                    if app.LeadTimeButton.Value
                        matchedData = matchedData(~strcmp(matchedData.LeadTime, 'None'), :);
                    end
            
                    % Apply Quoted Part Number filter if QuotedPNButton is pressed
                    if app.QuotedPNButton.Value
                        matchedData = matchedData(~cellfun(@isempty, matchedData.QuotedPN), :);
                    end


                    % Handle no matches
                    if height(matchedData) == 0
                        if ~isempty(query) % Skip adding empty queries (from filters)
                            notFoundQueries{end+1} = query;
                        end
                        continue; % Skip to next query
                    end

%                     if height(matchedData) == 0
%                         uialert(app.Pricify20UIFigure, ...
%                             sprintf('No matches found for: %s', query), ...
%                             'Search Results');
%                         return;
%                     end
                    
                    % Sort and remove duplicates
                    matchedData = sortrows(matchedData, 'Order');
%                     [~, idx] = unique(matchedData.Order);
%                     matchedData = matchedData(idx,:);
                    
                    % Calculate statistics
                    RecordCount = height(matchedData)
                    avgUP2C = mean(matchedData.UP2C(~isnan(matchedData.UP2C)));
                    avgQty = mean(matchedData.FinalQuantity(~isnan(matchedData.FinalQuantity)));
                    avgRatio = mean(matchedData.UPRatio(~isnan(matchedData.UPRatio)));
                    
                    [sources, ~, ic] = unique(matchedData.Source);
                    [~, maxIdx] = max(accumarray(ic,1));
                    favoriteSource = sources{maxIdx};

                    % Add individual records
                    for i = 1:height(matchedData)
                        row = matchedData(i,:);
                        results = [results; {
                        row.Order,...
                        datestr(row.Date),...
                        row.PartNumber,...
                        row.QuotedPN,...
                        sprintf('%.3f', row.UP2C),...
                        sprintf('%.4f', row.PurchasedUP),...
                        sprintf('%.2f', row.UPRatio),...
                        num2str(row.FinalQuantity),...
                        row.Source,...
                        row.LeadTime
                    }];
                    end
                
                    if ~isempty(query)
                        % Add summary row
                        results = [results; {
                            sprintf('Record Count = %.0f', RecordCount), '', sprintf('Searched Part Number: %s', query), '', ...
                            sprintf('Suggested UP2C = %.3f', avgUP2C), ...
                            '', ...
                            sprintf('Estimated UP Ratio: %.2f', avgRatio), ...
                            sprintf('For Quantity of, %.0f', avgQty), ...
                            sprintf('Favorite Source: %s', favoriteSource), ...
                            ''
                        }];
                    end
                end
%                     % Store extracted results
%                     lastRow = matchedData(end,:);
%                     app.ExtractedResults = [app.ExtractedResults; {
%                         query, ...
%                         sprintf('%.2f', avgUP2C), ...
%                         sprintf('%.0f', avgQty), ...
%                         sprintf('%.2f', avgRatio), ...
%                         favoriteSource, ...
%                         lastRow.Order, ...
%                         datestr(lastRow.Date), ...
%                         lastRow.PartNumber, ...
%                         sprintf('%.2f', lastRow.UP2C), ...
%                         sprintf('%.3f', lastRow.PurchasedUP), ...
%                         sprintf('%.2f', lastRow.UPRatio), ...
%                         num2str(lastRow.FinalQuantity), ...
%                         lastRow.Source, ...
%                         lastRow.LeadTime
%                     }];
%                  end


                % Show alert for not found queries after processing all
                if ~isempty(notFoundQueries)
                    uialert(app.Pricify20UIFigure, ...
                        sprintf('No matches found for:\n%s', strjoin(notFoundQueries, '\n')), ...
                        'Search Results');
                end


                % Create a new variable to store cleaned-up data
                cleaned_results = results;
                
                % Loop through the results and replace cells with sub-cells or empty cells
                for i = 1:size(results, 1)
                    for j = 1:size(results, 2)
                        if iscell(results{i, j})
                            % Check if the cell contains another cell or a string and flatten
                            if numel(results{i, j}) == 1 && ischar(results{i, j}{1})
                                cleaned_results{i, j} = results{i, j}{1};  % Extract string
                            elseif numel(results{i, j}) == 1 && isnumeric(results{i, j}{1})
                                cleaned_results{i, j} = results{i, j}{1};  % Extract number
                            else
                                cleaned_results{i, j} = '';  % Handle any complex cells as empty string
                            end
                        elseif isempty(results{i, j})
                            cleaned_results{i, j} = '';  % Handle empty cells
                        end
                    end
                end


                % Set the cleaned-up data to the table
                app.Searched_Table.Data = cleaned_results;
                % Create a style object with a black background and white font color
                blackWhiteStyle = uistyle('BackgroundColor', [0, 0, 0], 'FontColor', [1, 1, 1]);
            
                % Identify summary rows based on the content of the first column
                for rowIdx = 1:height(cleaned_results)
                    if contains(cleaned_results{rowIdx, 1}, 'Record Count =')
                        % Apply the black background and white font color style to this summary row
                        addStyle(app.Searched_Table, blackWhiteStyle, 'row', rowIdx);
                    end
                end
            close(progressDlg);


        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)

                progressDlg = uiprogressdlg(app.Pricify20UIFigure, ...
                    'Title', 'Refreshing...', ...
                    'Message', 'Please wait :)', ...
                    'Indeterminate', true, ...
                    'Cancelable', false);

                app.SearchableDatabaseTabButtonDown;
                
                close(progressDlg);


        end

        % Value changed function: ExactButton
        function ExactButtonValueChanged(app, event)
            app.exactSearch = app.ExactButton.Value;
            
        end

        % Button pushed function: ImportInquirylistButton
        function ImportInquirylistButtonPushed(app, event)
       
            
            removeStyle(app.PreviewTable);
            
            % Create a style object with center alignment
            centerAlignStyle = uistyle('HorizontalAlignment',"left");


            [file2, path2] = uigetfile('*.xlsx', 'Select the Inquiry List Excel File');
            if file2 == 0
                % User canceled the file selection
                return;
            end
            progressDlg = uiprogressdlg(app.Pricify20UIFigure, ...
                'Title', 'Loading...', ...
                'Message', 'Please wait :)', ...
                'Indeterminate', true, ...
                'Cancelable', false);


            Inql = fullfile(path2, file2);
        
            % Read the Excel file into a table
            Inq = readtable(Inql);
        
            % Extract the first column (index or identifier)
            line = Inq{:, 1}; % Assuming the first column is unnamed
            PN = Inq{:, 2};


            % Extract 'Part number' and 'Quantity' columns
            Quantity = Inq{:, 'Quantity'};

            % Clean part numbers from the database
            cleanedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('PartNumber'), 'UniformOutput', false);
            cleanedQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('QuotedPN'), 'UniformOutput', false);
 
            results = cell(0, 4); % 4 columns: Part Number, Quantity, Record Count, Favorite Source

            for q = 1:length(PN)
                query = PN{q};
                cleanQuery = app.cleanPartNumber(query);
                % Perform search
                matches = contains(cleanedPN, cleanQuery, 'IgnoreCase', true) | ...
                         contains(cleanedQuotedPN, cleanQuery, 'IgnoreCase', true);


                matchedData = app.DealtDB(matches,:);
                RecordCount = height(matchedData); % Get the number of matched records
                
                % Check if the part number already exists in results
                existingRowIdx = find(strcmp(results(:,2), query), 1);
                 if isempty(existingRowIdx)
                    % If part number is not in results yet, add it
                    results = [results; {
                        line(q), ...          % Line from the Excel file
                        query, ...           % Part Number
                        Quantity(q), ...     % Quantity from the XLSX file
                        RecordCount          % Number of matched records
                    }];
                else
                    % If part number exists, update the existing row
                    results{existingRowIdx, 3} = results{existingRowIdx, 3} + Quantity(q);  % Sum quantities
                    results{existingRowIdx, 4} = results{existingRowIdx, 4} + RecordCount;  % Sum record count
                end
            end                             
  

            % Create a table with the results
            Temp = cell2table(results, 'VariableNames', {'Line', 'Part Number', 'Quantity', 'Record Count'});

            % Apply the center alignment style to the entire table
            addStyle(app.PreviewTable, centerAlignStyle, 'table', '');
            % Create a style object with a black background and white font color
            blackWhiteStyle = uistyle('BackgroundColor', [1, 0.8, 0.8]);
            
            % Identify summary rows where 'Record Count' is 0
            for rowIdx = 1:height(Temp)
                if Temp.('Record Count')(rowIdx) == 0
                    % Apply the black background and white font color style to this row
                    addStyle(app.PreviewTable, blackWhiteStyle, 'row', rowIdx);
                end
            end

            % Store the Temp table in app
            app.Temp = Temp;
            % Update the UITable with the new data
            app.PreviewTable.Data = Temp;
            % Loop through the rows of Temp where 'Record Count' is 0
            for rowIdx = 1:height(Temp)
                if Temp.('Record Count')(rowIdx) == 0
                    % Extract the part number and other info for rows with 0 record count
                    partNumber = Temp.('Part Number'){rowIdx};
                    lineNumber = Temp.('Line')(rowIdx);
            
                    % Search through app.UndealtDB for matching part numbers
                    matches = strcmp(app.UndealtDB.InquiredPN, partNumber);
                    matchedData = app.UndealtDB(matches, :);
            
                    if ~isempty(matchedData)
                        % Extract the relevant columns from the matched data
                        Order = matchedData.Order;
                        InquiredPN = matchedData.InquiredPN;
                        InquiredQuantity = matchedData.InquiredQuantity;
                        QuotedQuantity = matchedData.QuotedQuantity;
                        UP2C = matchedData.UP2C;
            
                        % Prepare the data to add to the new table
                        % Include the line number from app.PreviewTable
                        notFoundData = cell(length(Order), 5); % 5 columns: No., Order, Inq. Part Number, Inq. Quantity, Quoted Quantity, UP2C
            
                        for i = 1:length(Order)
                            notFoundData{i, 1} = lineNumber;         % Line number from PreviewTable
                            notFoundData{i, 2} = Order{i};           % Order
                            notFoundData{i, 3} = InquiredPN{i};      % Inquired PN
                            notFoundData{i, 4} = InquiredQuantity(i);% Inquired Quantity
                            notFoundData{i, 5} = QuotedQuantity(i);  % Quoted Quantity
                            notFoundData{i, 6} = UP2C(i);            % UP2C
                        end
            
                        % Create a table for the NotFound data
                        NFT = cell2table(notFoundData, 'VariableNames', ...
                            {'No.', 'Order', 'Inq. Part Number', 'Inq. Quantity', 'Quoted Quantity', 'UP2C'});
            
                        % Update the NotFoundTable in the UI
                        app.NotFoundTable.Data = NFT;
                    end
                end
            end
            addStyle(app.NotFoundTable, centerAlignStyle, 'table', '');

            close(progressDlg);

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
%             progressDlg = uiprogressdlg(app.Pricify20UIFigure, ...
%                 'Title', 'Loading...', ...
%                 'Message', 'Please wait :)', ...
%                 'Indeterminate', true, ...
%                 'Cancelable', false);
%             
%             % Check if Temp data is available
%             if isempty(app.Temp) || height(app.Temp) == 0
%                 uialert(app.Pricify20UIFigure, 'Please import an inquiry list first.', 'No Data');
%                 return;
%             end
% 
% 
%             % Helper function to format dates
%             function formattedDate = formatDate(dateValue)
%                 if isdatetime(dateValue)
%                     formattedDate = datestr(dateValue, 'dd-mmm-yyyy');
%                 elseif ischar(dateValue) || isstring(dateValue)
%                     try
%                         formattedDate = datestr(datetime(dateValue, 'InputFormat', 'dd-MMM-yyyy'), 'dd-mmm-yyyy');
%                     catch
%                         formattedDate = 'N/A';
%                     end
%                 else
%                     formattedDate = 'N/A';
%                 end
%             end
% 
%             
%             if app.MinMaxBreakdownCheckBox.Value == true
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%         
%                 
%                 % Filter parts with Record Count > 0
%                 validParts = app.Temp(app.Temp.('Record Count') > 0, :);
%                 
%                 % Precompute cleaned part numbers from DealtDB
%                 cleanedDealtPN = cellfun(@app.cleanPartNumber, app.DealtDB.PartNumber, 'UniformOutput', false);
%                 cleanedDealtQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.QuotedPN, 'UniformOutput', false);
%                 
%                 % Initialize output data (8 columns)
%                 outputData = cell(0, 8); % Format: 
%                 % [Min_PurchasedUP, Min_Quantity, Min_Source, Min_Date, 
%                 %  Max_PurchasedUP, Max_Quantity, Max_Source, Max_Date]
%                 
%                 for i = 1:height(validParts)
%                     partNumber = validParts.('Part Number'){i};
%                     cleanQuery = app.cleanPartNumber(partNumber);
%                     
%                     % Find matches in DealtDB
%                     matches = contains(cleanedDealtPN, cleanQuery, 'IgnoreCase', true) | ...
%                              contains(cleanedDealtQuotedPN, cleanQuery, 'IgnoreCase', true);
%                     
%                     if any(matches)
%                         matchedData = app.DealtDB(matches, :);
%                         
%                         % Get min PurchasedUP and its details
%                         [minUP, minIdx] = min(matchedData.PurchasedUP);
%                         minRows = matchedData(matchedData.PurchasedUP == minUP, :);
%                         minRows = sortrows(minRows, 'Date', 'descend'); % Get latest date for minUP
%                         minRow = minRows(1, :);
%                         
%                         % Get max PurchasedUP and its details
%                         [maxUP, maxIdx] = max(matchedData.PurchasedUP);
%                         maxRows = matchedData(matchedData.PurchasedUP == maxUP, :);
%                         maxRows = sortrows(maxRows, 'Date', 'descend'); % Get latest date for maxUP
%                         maxRow = maxRows(1, :);
%                         
%                         % Format dates
%                         minDate = formatDate(minRow.Date);
%                         maxDate = formatDate(maxRow.Date);
%                         
%                         % Add to output
%                         outputData(end+1, :) = {minUP, minRow.FinalQuantity, minRow.Source{1}, minDate,...
%                                                 maxUP, maxRow.FinalQuantity, maxRow.Source{1}, maxDate};
%                     else
%                         % Fallback (should not trigger since Record Count > 0)
%                         outputData(end+1, :) = {NaN, NaN, 'N/A', 'N/A', NaN, NaN, 'N/A', 'N/A'};
%                     end
%                 end
%                 
%                 % Convert to table and update OutputTable
%                 outputTable = cell2table(outputData, 'VariableNames', ...
%                     {'Min P_UP', 'Quantity MinPUP', 'Source MinPUP', 'Date MinPUP',...
%                      'Max P_UP', 'Quantity MaxPUP', 'Source MaxPUP', 'Date MaxPUP'});
%                 
%                 % Set column names and update table
%                 app.OutputTable.ColumnName = outputTable.Properties.VariableNames;
%                 app.OutputTable.Data = outputTable;
%                 
%                 % Apply alignment style
%                 centerAlignStyle = uistyle('HorizontalAlignment', 'left');
%                 addStyle(app.OutputTable, centerAlignStyle, 'table', '');
%             end
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% 
% 
% 
% 
% 
% 
%             if app.LastOrderCheckBox.Value == true
%  %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                
%                 % Filter parts with Record Count > 0
%                 validParts = app.Temp(app.Temp.('Record Count') > 0, :);
%                 
%                 % Precompute cleaned part numbers from DealtDB
%                 cleanedDealtPN = cellfun(@app.cleanPartNumber, app.DealtDB.PartNumber, 'UniformOutput', false);
%                 cleanedDealtQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.QuotedPN, 'UniformOutput', false);
%                 
%                 % Initialize output data
%                 outputData = cell(0, 6); % Order, Date, UP2C, PurchasedUP, Quantity, Source
%                 
%                 for i = 1:height(validParts)
%                     partNumber = validParts.('Part Number'){i};
%                     cleanQuery = app.cleanPartNumber(partNumber);
%                     
%                     % Find matches in DealtDB
%                     matches = contains(cleanedDealtPN, cleanQuery, 'IgnoreCase', true) | ...
%                              contains(cleanedDealtQuotedPN, cleanQuery, 'IgnoreCase', true);
%                     
%                     if any(matches)
%                         matchedData = app.DealtDB(matches, :);
%                         % Sort matchedData by Date in ascending order
%                         matchedData = sortrows(matchedData, 'Date', 'ascend');
%                         lastRow = matchedData(end, :); % Get the last matched row
%                         
%                         % Extract required data (adjust based on DealtDB's variable names/types)
%                         order = lastRow.Order;
%                         date = lastRow.Date;
%                         up2c = lastRow.UP2C;
%                         purchasedUP = lastRow.PurchasedUP;
%                         quantity = lastRow.FinalQuantity;
%                         source = lastRow.Source;
%                         
%                         % Handle data types (e.g., convert datetime to string)
%                         if isdatetime(date)
%                             date = datestr(date, 'dd-mmm-yyyy');
%                         end
%                         
%                         outputData(end+1, :) = {order, date, up2c, purchasedUP, quantity, source};
%                     else
%                         % Fallback if no matches (should not occur)
%                         outputData(end+1, :) = {'N/A', 'N/A', NaN, NaN, NaN, 'N/A'};
%                     end
%                 end
%                 
%                 % Convert to table and update OutputTable
%                 outputTable = cell2table(outputData, 'VariableNames', ...
%                     {'Order', 'Date', 'UP2C', 'Purchased UP', 'Quantity', 'Source'});
%                 app.OutputTable.ColumnName = outputTable.Properties.VariableNames;
%                 app.OutputTable.Data = outputTable;
%                 
%                 % Apply alignment style
%                 centerAlignStyle = uistyle('HorizontalAlignment', 'left');
%                 addStyle(app.OutputTable, centerAlignStyle, 'table', '');
%             end
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% 
% 
% 
% 
% 
%             if app.PricifySuggestionCheckBox.Value == true
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 % Create a style object with center alignment
%                 centerAlignStyle = uistyle('HorizontalAlignment',"left");
%             
%                 % Prepare an empty cell array for output results
%                 outputResults = cell(0, 7); % 7 columns: No., Inq. Part Number, Inq. Quantity, Quoted Quantity, Lead Time, Suggested UP2C, Favorite Source
%                 % Access Temp from app
%                 Temp = app.Temp;
%                 % Iterate over rows where 'Record Count' is not 0
%                 for rowIdx = 1:height(Temp)
%                     if Temp.('Record Count')(rowIdx) ~= 0
%                         % Retrieve the data for the current row
%                         line = Temp.('Line')(rowIdx); % No. from PreviewTable
%                         partNumber = Temp.('Part Number')(rowIdx); % Inq. Part Number
%                         CpartNumber = app.cleanPartNumber(partNumber);
%                         inqQuantity = Temp.Quantity(rowIdx); % Inq. Quantity
%                 
%                         % Find matching records in app.DealtDB based on the Inq. Part Number
%                         cleanedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('PartNumber'), 'UniformOutput', false);
%                         cleanedQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('QuotedPN'), 'UniformOutput', false);
%                 
%                         % Perform search in app.DealtDB for the part number (exact match or contains)
%                         %matches = strcmp(cleanedPN, partNumber) | contains(cleanedQuotedPN, partNumber, 'IgnoreCase', true);
%                         matches = contains(cleanedPN, CpartNumber, 'IgnoreCase', true) | ...
%                              contains(cleanedQuotedPN, CpartNumber, 'IgnoreCase', true);
%                         matchedData = app.DealtDB(matches, :);
%     
%                         % Initialize the values as dashes for non-matching cases
%                         qpn = '-';
%                         leadTime = '-';
%                         favoriteSource = '-';
%                         avgUP2C = '-';
%                         if height(matchedData) > 0
%         
%                             % Filter matched data based on Quantity range (0.8 to 1.2 of Inq. Quantity)
%                             quantityRangeMatches = matchedData.FinalQuantity >= (0.8 * inqQuantity) & matchedData.FinalQuantity <= (1.2 * inqQuantity);
%                             matchedData = matchedData(quantityRangeMatches, :);
%                             % Calculate the average UP2C (excluding NaN values)
%                             if height(matchedData) > 0
%                                 avgUP2C = mean(matchedData.UP2C(~isnan(matchedData.UP2C)));                   
%                             % Calculate favorite source based on most frequent Source
%                                 [sources, ~, ic] = unique(matchedData.Source);
%                                 % Check if sources is empty before proceeding
%                                 if ~isempty(sources)
%                                     [~, maxIdx] = max(accumarray(ic, 1)); % Get the index of the most frequent source
%                                     favoriteSource = sources{maxIdx};     % Set the favorite source
%                                 end
%                                 if any(~cellfun(@isempty, matchedData.QuotedPN))
%                                     qpn = matchedData.QuotedPN(1); % Assuming the first match has the quoted quantity
%                                 end
%                                     
%                                 % Get the Lead Time, ensuring it's not empty
%                                 leadTime = matchedData.LeadTime(1);  % Assuming there is at least one row
%                             end
%                             if isnumeric(avgUP2C)
%                                 avgUP2CStr = num2str(avgUP2C, '%.3f'); % Format to 4 decimal places
%                             else
%                                 avgUP2CStr = avgUP2C; % Keep as '-' if not numeric
%                             end
%     
%                             outputResults = [outputResults; {
%                                 line, ...               % No. from PreviewTable
%                                 partNumber, ...         % Inq. Part Number
%                                 inqQuantity, ...        % Inq. Quantity
%                                 qpn, ...     % Quoted Quantity (if exists)
%                                 leadTime, ...           % Lead Time
%                                 avgUP2CStr, ...            % Suggested UP2C
%                                 favoriteSource         % Favorite Source
%                             }];
%                         end
%                     end
%                 end
%                 % Create the output table with the results
%                 OutputTable = cell2table(outputResults, 'VariableNames', {'No.', 'Inq. Part Number', 'Inq. Quantity', 'Quoted PN', 'Lead Time', 'Suggested UP2C', 'Favorite Source'});
%                 app.OutputTable.ColumnName = OutputTable.Properties.VariableNames;
% 
%                 % Update the OutputTable in the app
%                 app.OutputTable.Data = OutputTable;
%                 addStyle(app.OutputTable, centerAlignStyle, 'table', '');
%             end
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%           
% 
%             close(progressDlg);


            progressDlg = uiprogressdlg(app.Pricify20UIFigure, ...
                'Title', 'Loading...', ...
                'Message', 'Please wait :)', ...
                'Indeterminate', true, ...
                'Cancelable', false);

            % Get tolerance value from slider (in percentage)
            tolerancePercent = app.ToleranceSlider.Value;  % e.g., 20 for 20%
            lowerTolerance = 1 - tolerancePercent;
            upperTolerance = 1 + tolerancePercent;

            % Check if Temp data is available
            if isempty(app.Temp) || height(app.Temp) == 0
                uialert(app.Pricify20UIFigure, 'Please import an inquiry list first.', 'No Data');
                close(progressDlg); % Close progress dialog
                return;
            end
        
            % Check if any checkbox is selected
            if ~(app.MinMaxBreakdownCheckBox.Value || app.LastOrderCheckBox.Value || app.PricifySuggestionCheckBox.Value)
                uialert(app.Pricify20UIFigure, 'Please select at least one option.', 'No Option Selected');
                close(progressDlg); % Close progress dialog
                return;
            end
        
             % Extract valid parts (Record Count > 0) and create base table
            validParts = app.Temp(app.Temp.('Record Count') > 0, :);
            baseTable = table(validParts.Line, validParts.('Part Number'), validParts.Quantity, ...
                'VariableNames', {'No.', 'Inq. Part Number', 'Inq. Quantity'});

            % Helper function to format dates
            function formattedDate = formatDate(dateValue)
                if isdatetime(dateValue)
                    formattedDate = datestr(dateValue, 'dd-mmm-yyyy');
                elseif ischar(dateValue) || isstring(dateValue)
                    try
                        formattedDate = datestr(datetime(dateValue, 'InputFormat', 'dd-MMM-yyyy'), 'dd-mmm-yyyy');
                    catch
                        formattedDate = 'N/A';
                    end
                else
                    formattedDate = 'N/A';
                end
            end
        
            % Initialize variables to hold individual tables
            minMaxTable = [];
            lastOrderTable = [];
            pricifyTable = [];
            
            % Process each checkbox and generate respective tables
            if app.MinMaxBreakdownCheckBox.Value
                % Filter parts with Record Count > 0
                
                % Precompute cleaned part numbers from DealtDB
                cleanedDealtPN = cellfun(@app.cleanPartNumber, app.DealtDB.PartNumber, 'UniformOutput', false);
                cleanedDealtQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.QuotedPN, 'UniformOutput', false);
                
                % Initialize output data (8 columns)
                outputData = cell(height(validParts), 8);
                
                for i = 1:height(validParts)
                    partNumber = validParts.('Part Number'){i};
                    cleanQuery = app.cleanPartNumber(partNumber);
                    
                    % Find matches in DealtDB
                    matches = contains(cleanedDealtPN, cleanQuery, 'IgnoreCase', true) | ...
                             contains(cleanedDealtQuotedPN, cleanQuery, 'IgnoreCase', true);
                    
                    if any(matches)
                        matchedData = app.DealtDB(matches, :);
                        
                        % Get min PurchasedUP and its details
                        [minUP, ~] = min(matchedData.PurchasedUP);
                        minRows = matchedData(matchedData.PurchasedUP == minUP, :);
                        minRows = sortrows(minRows, 'Date', 'descend');
                        minRow = minRows(1, :);
                        
                        % Get max PurchasedUP and its details
                        [maxUP, ~] = max(matchedData.PurchasedUP);
                        maxRows = matchedData(matchedData.PurchasedUP == maxUP, :);
                        maxRows = sortrows(maxRows, 'Date', 'descend');
                        maxRow = maxRows(1, :);
                        
                        % Format dates
                        minDate = formatDate(minRow.Date);
                        maxDate = formatDate(maxRow.Date);
                        
                        % Add to output
                        outputData(i, :) = {minUP, minRow.FinalQuantity, minRow.Source{1}, minDate,...
                                                maxUP, maxRow.FinalQuantity, maxRow.Source{1}, maxDate};
                    else
                        outputData(i, :) = {NaN, NaN, 'N/A', 'N/A', NaN, NaN, 'N/A', 'N/A'};
                    end
                end
                
                % Convert to table
                minMaxTable = cell2table(outputData, 'VariableNames', ...
                    {'Min P_UP', 'Quantity MinPUP', 'Source MinPUP', 'Date MinPUP',...
                     'Max P_UP', 'Quantity MaxPUP', 'Source MaxPUP', 'Date MaxPUP'});
            end
        
            if app.LastOrderCheckBox.Value
                % Filter parts with Record Count > 0
                
                % Precompute cleaned part numbers from DealtDB
                cleanedDealtPN = cellfun(@app.cleanPartNumber, app.DealtDB.PartNumber, 'UniformOutput', false);
                cleanedDealtQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.QuotedPN, 'UniformOutput', false);
                
                % Initialize output data
                outputData = cell(height(validParts), 6);
                
                for i = 1:height(validParts)
                    partNumber = validParts.('Part Number'){i};
                    cleanQuery = app.cleanPartNumber(partNumber);
                    
                    % Find matches in DealtDB
                    matches = contains(cleanedDealtPN, cleanQuery, 'IgnoreCase', true) | ...
                             contains(cleanedDealtQuotedPN, cleanQuery, 'IgnoreCase', true);
                    
                    if any(matches)
                        matchedData = app.DealtDB(matches, :);
                        matchedData = sortrows(matchedData, 'Date', 'ascend');
                        lastRow = matchedData(end, :);
                        
                        % Handle data types
                        date = formatDate(lastRow.Date);
                        outputData(i, :) = {lastRow.Order, date, lastRow.UP2C, ...
                                                lastRow.PurchasedUP, lastRow.FinalQuantity, lastRow.Source{1}};
                    else
                        outputData(i, :) = {'N/A', 'N/A', NaN, NaN, NaN, 'N/A'};
                    end
                end
                
                % Convert to table
                lastOrderTable = cell2table(outputData, 'VariableNames', ...
                    {'Last Order', 'Date', 'UP2C', 'Purchased UP', 'Quantity', 'Source'});
            end
        
            if app.PricifySuggestionCheckBox.Value
                centerAlignStyle = uistyle('HorizontalAlignment', 'left');
                outputResults = cell(height(validParts), 4);
                Temp = validParts; % Use the pre-filtered valid parts
                
                for rowIdx = 1:height(Temp)
                    if Temp.('Record Count')(rowIdx) ~= 0
                        CpartNumber = app.cleanPartNumber(Temp.('Part Number'){rowIdx});
                        inqQuantity = Temp.Quantity(rowIdx);
                        
                        cleanedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('PartNumber'), 'UniformOutput', false);
                        cleanedQuotedPN = cellfun(@app.cleanPartNumber, app.DealtDB.('QuotedPN'), 'UniformOutput', false);
                        
                        matches = contains(cleanedPN, CpartNumber, 'IgnoreCase', true) | ...
                                 contains(cleanedQuotedPN, CpartNumber, 'IgnoreCase', true);
                        matchedData = app.DealtDB(matches, :);
            
                        qpn = '-';
                        leadTime = '-';
                        favoriteSource = '-';
                        avgUP2C = '-';
                        if height(matchedData) > 0
                            quantityRangeMatches = matchedData.FinalQuantity >= (lowerTolerance * inqQuantity) & ...
                                                   matchedData.FinalQuantity <= (upperTolerance * inqQuantity);
                            matchedData = matchedData(quantityRangeMatches, :);

                            if height(matchedData) > 0
                                avgUP2C = mean(matchedData.UP2C(~isnan(matchedData.UP2C)));
                                [sources, ~, ic] = unique(matchedData.Source);
                                if ~isempty(sources)
                                    [~, maxIdx] = max(accumarray(ic, 1));
                                    favoriteSource = sources{maxIdx};
                                end
                                if any(~cellfun(@isempty, matchedData.QuotedPN))
                                    qpn = matchedData.QuotedPN{1};
                                end
                                leadTime = matchedData.LeadTime(1);
                            end
                            
                            avgUP2CStr = num2str(avgUP2C, '%.3f');
                            outputResults(rowIdx, :) = {qpn, leadTime, avgUP2CStr, favoriteSource};
                        end
                    end
                end
                
                % Convert to table
                pricifyTable = cell2table(outputResults, 'VariableNames', ...
                    {'Quoted PN', 'Lead Time', 'Suggested UP2C', 'Favorite Source'});
            end
        
            % Combine tables from selected checkboxes
            tablesToCombine = {baseTable};
            if ~isempty(pricifyTable), tablesToCombine{end+1} = pricifyTable; end
            if ~isempty(lastOrderTable), tablesToCombine{end+1} = lastOrderTable; end
            if ~isempty(minMaxTable), tablesToCombine{end+1} = minMaxTable; end


            if isempty(tablesToCombine)
                close(progressDlg);
                return;
            else
                combinedTable = horzcat(tablesToCombine{:});
            end
        
            % Update OutputTable with combined data
            app.OutputTable.Data = combinedTable;
            app.OutputTable.ColumnName = combinedTable.Properties.VariableNames;
            
            % Apply alignment style
            centerAlignStyle = uistyle('HorizontalAlignment', 'left');
            addStyle(app.OutputTable, centerAlignStyle, 'table', '');
            
            close(progressDlg);

        end

        % Button pushed function: ExtractButton
        function ExtractButtonPushed(app, event)
            %% Export to Excel Inq Tab
            [file, path] = uiputfile('*.xlsx', "Save File As");
            fullpathname = fullfile(path, file);
            
            if fullpathname ~= 0
                delete(fullpathname); % Delete the file if it already exists
                
                % Convert table to cell array
                dataCell = table2cell(app.OutputTable.Data);
                
                % Ensure dataCell is a 2-D cell array
                if isvector(dataCell)
                    dataCell = dataCell(:); % Convert to a column vector
                end
                
                % Convert cell array to table
                dataTable = cell2table(dataCell, 'VariableNames', app.OutputTable.ColumnName);
                
                writetable(dataTable, fullpathname); % Write table to Excel
                winopen(fullpathname); % Open the file
            end
        end

        % Button pushed function: Excel_Export
        function Excel_ExportButtonPushed(app, event)
            %             if isempty(app.extractedResults)
%                 app.Notif_Tab2.Value = 'No results to export.';
%                 return;
%             end
%             
%             % Convert to table and save
%             columns = {'SearchedPN', 'SuggestedUP2C', 'Quantity', 'UPRatio', 'FavoriteSource', ...
%                       'Order', 'Date', 'PartNumber', 'UP2C', 'PurchasedUP', 'UPRatio', 'FinalQuantity', 'Source', 'LeadTime'};
%             T = cell2table(app.extractedResults, 'VariableNames', columns);
%             
%             [file, path] = uiputfile('*.xlsx', 'Save Results');
%             if file == 0
%                 return; 
%             end
%             writetable(T, fullfile(path, file));
%             app.Notif_Tab2.Value = sprintf('Exported to %s', file);
% 

            %% Export to excel Inq Tab
            [file, path] = uiputfile('*.xlsx', "Save File As");
            fullpathname = fullfile(path, file);
            
            if fullpathname ~= 0
                delete(fullpathname); % Delete the file if it already exists
                
                % Convert cell array to table
                dataTable = cell2table(app.Searched_Table.Data, ...
                    'VariableNames', app.Searched_Table.ColumnName);
                
                writetable(dataTable, fullpathname); % Write table to Excel
                winopen(fullpathname); % Open the file
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Pricify20UIFigure = uifigure('Visible', 'off');
            app.Pricify20UIFigure.Name = 'Pricify 2.0';
            app.Pricify20UIFigure.Icon = fullfile(pathToMLAPP, 'PNG', 'Vira.png');
            app.GridLayout = uigridlayout(app.Pricify20UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WelcomeTab = uitab(app.TabGroup);
            app.WelcomeTab.Title = 'Welcome';
            app.WelcomeTab.ButtonDownFcn = createCallbackFcn(app, @WelcomeTabButtonDown, true);
            app.GridTab1 = uigridlayout(app.WelcomeTab);
            app.VIRALabel = uilabel(app.GridTab1);
            app.VIRALabel.Text = 'VIRA E.P.C.';
            app.WelcomeLabel = uilabel(app.GridTab1);
            app.WelcomeLabel.Text = 'Welcome to Pricify 2.0';
            app.InfoLabel = uilabel(app.GridTab1);
            app.InfoLabel.Text = '© 2025 Pricify, Vira EPC. Powered by Alireza Dehghan.';
            app.SearchableDatabaseTab = uitab(app.TabGroup);
            app.SearchableDatabaseTab.Title = 'Searchable Database';
            app.SearchableDatabaseTab.ButtonDownFcn = createCallbackFcn(app, @SearchableDatabaseTabButtonDown, true);
            app.GridTab2 = uigridlayout(app.SearchableDatabaseTab);
            app.RefreshButton = uibutton(app.GridTab2, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = fullfile(pathToMLAPP, 'PNG', 'refresh (2).png');
            app.RefreshButton.Text = 'Refresh';
            app.OrderDD = uidropdown(app.GridTab2);
            app.OrderDD.Items = {'Order'};
            app.OrderDD.Value = 'Order';
            app.SearchButton = uibutton(app.GridTab2, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Icon = fullfile(pathToMLAPP, 'PNG', 'search.png');
            app.SearchButton.Text = 'Search';
            app.LeadTimeButton = uibutton(app.GridTab2, 'state');
            app.LeadTimeButton.Text = 'Lead Time';
            app.QuotedPNButton = uibutton(app.GridTab2, 'state');
            app.QuotedPNButton.Text = 'Quoted PN';
            app.ExactButton = uibutton(app.GridTab2, 'state');
            app.ExactButton.ValueChangedFcn = createCallbackFcn(app, @ExactButtonValueChanged, true);
            app.ExactButton.Text = 'Exact';
            app.PartNumbersLabel = uilabel(app.GridTab2);
            app.PartNumbersLabel.Tooltip = {'one per line'};
            app.PartNumbersLabel.Text = 'Part Numbers:';
            app.SourceDD = uidropdown(app.GridTab2);
            app.SourceDD.Items = {'Source'};
            app.SourceDD.Value = 'Source';
            app.Searched_Table = uitable(app.GridTab2);
            app.Searched_Table.ColumnName = {'Order'; 'Date'; 'Part Number'; 'Quoted PN'; 'UP2C'; 'Purchased UP'; 'UP Ratio'; 'Quantity'; 'Source'; 'Lead Time'};
            app.Searched_Table.RowName = {};
            app.Searched_Table.ColumnSortable = true;
            app.PartNumberTextArea = uitextarea(app.GridTab2);
            app.Excel_Export = uibutton(app.GridTab2, 'push');
            app.Excel_Export.ButtonPushedFcn = createCallbackFcn(app, @Excel_ExportButtonPushed, true);
            app.Excel_Export.Icon = fullfile(pathToMLAPP, 'PNG', 'excel (1).png');
            app.Excel_Export.Text = 'Extract';
            app.PricingAssistantTab = uitab(app.TabGroup);
            app.PricingAssistantTab.Title = 'Pricing Assistant';
            app.PricingAssistantTab.ButtonDownFcn = createCallbackFcn(app, @PricingAssistantTabButtonDown, true);
            app.GridTab3 = uigridlayout(app.PricingAssistantTab);
            app.PreviewTable = uitable(app.GridTab3);
            app.PreviewTable.ColumnName = {'No.'; 'Part Number'; 'Quantity'; 'Record Count'};
            app.PreviewTable.RowName = {};
            app.PreviewTable.ColumnSortable = [true false false true];
            app.PreviewTable.Tooltip = {'The List.'};
            app.ImportInquirylistButton = uibutton(app.GridTab3, 'push');
            app.ImportInquirylistButton.ButtonPushedFcn = createCallbackFcn(app, @ImportInquirylistButtonPushed, true);
            app.ImportInquirylistButton.Icon = fullfile(pathToMLAPP, 'PNG', 'folder.png');
            app.ImportInquirylistButton.Text = 'Import Inquiry list';
            app.PricifySuggestionCheckBox = uicheckbox(app.GridTab3);
            app.PricifySuggestionCheckBox.Text = 'Pricify Suggestion';
            app.ExtractButton = uibutton(app.GridTab3, 'push');
            app.ExtractButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractButtonPushed, true);
            app.ExtractButton.Icon = fullfile(pathToMLAPP, 'PNG', 'excel (1).png');
            app.ExtractButton.Text = 'Extract';
            app.OutputTable = uitable(app.GridTab3);
            app.OutputTable.ColumnName = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.OutputTable.RowName = {};
            app.OutputTable.ColumnSortable = [true false false false false false false false true false false false false false false false false false false false false];
            app.RunButton = uibutton(app.GridTab3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Icon = fullfile(pathToMLAPP, 'PNG', 'shuttle.png');
            app.RunButton.Text = 'Run';
            app.NotFoundTable = uitable(app.GridTab3);
            app.NotFoundTable.ColumnName = {'No.'; 'Order'; 'Inq. Part Number'; 'Inq. Quantity'; 'Quoted Quantity'; 'UP2C'};
            app.NotFoundTable.RowName = {};
            app.NotFoundTable.ColumnSortable = [true true true true false false];
            app.NotFoundTable.Tooltip = {'Parts Not Found History Records.'};
            app.MinMaxBreakdownCheckBox = uicheckbox(app.GridTab3);
            app.MinMaxBreakdownCheckBox.Text = 'Min-Max Breakdown';
            app.LastOrderCheckBox = uicheckbox(app.GridTab3);
            app.LastOrderCheckBox.Text = 'Last Order';
            app.GridLayout2 = uigridlayout(app.GridTab3);
            app.ToleranceSlider = uislider(app.GridLayout2);
            app.ToleranceSlider.Limits = [0 10];
            app.ToleranceSlider.MajorTicks = [0 10];
            app.ToleranceSlider.MajorTickLabels = {'High', 'Low'};
            app.ToleranceSlider.MinorTicks = [];
            app.ToleranceSlider.Tooltip = {'Set the tolerance level for matching quantities. Move left for an exact match (0 tolerance) or right for a broader tolerance (up to ±1000%).'};
            app.ToleranceSlider.Value = 0.5;
            app.PricifyImage = uiimage(app.GridLayout);
            app.PricifyImage.ImageSource = fullfile(pathToMLAPP, 'PNG', 'Pricify-2-12-2025 (1).png');
            app.GridWaiting = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.Pricify20UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pricify_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Pricify20UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Pricify20UIFigure)
        end
    end
end